classdef ScoreReportGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        InputTab                   matlab.ui.container.Tab
        NofileloadedLabel          matlab.ui.control.Label
        ExerciseDropDown           matlab.ui.control.DropDown
        ProblemLabel               matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        MATLABGraderScoreReportGeneratorLabel  matlab.ui.control.Label
        LatePenaltyEditField       matlab.ui.control.NumericEditField
        LatePenaltyEditFieldLabel  matlab.ui.control.Label
        GenerateButton             matlab.ui.control.Button
        ReadFileButton             matlab.ui.control.Button
        NameListEditField          matlab.ui.control.EditField
        NameListEditFieldLabel     matlab.ui.control.Label
        ScoresTab                  matlab.ui.container.Tab
        ScoresTextArea             matlab.ui.control.TextArea
        ScoresTextAreaLabel        matlab.ui.control.Label
    end


    methods (Access = private)

        function [ sortedStruct ] = sort_struct(app, inputStruct, sortField)

            %This function sorts a struct array based on the specified field
            %The field name must be a char array, for example, 'Name' or 'Value'

            %use merge sort - base case is one item in list

            if length(inputStruct) == 1
                sortedStruct = inputStruct;
            else
                %divide into two parts and call this function recursively

                divider = floor(length(inputStruct)/2);
                list1 = inputStruct(1:divider);
                list2 = inputStruct(divider+1:end);

                sortedList1 = sort_struct(app, list1, sortField);
                sortedList2 = sort_struct(app, list2,sortField);

                sortedStruct = [];

                %Merge the two sorted lists
                %As each element is added to the merged list, delete it from the
                %sub-list
                while ~isempty(sortedList1) && ~isempty(sortedList2)

                    if sortedList1(1).(sortField) < sortedList2(1).(sortField)
                        sortedStruct = [sortedStruct sortedList1(1)];
                        sortedList1(1) = [];
                    else
                        sortedStruct = [sortedStruct sortedList2(1)];
                        sortedList2(1) = [];
                    end
                end

                %When one of the sublists is empty, the other one can be added to the
                %merged list as is

                if isempty(sortedList1)
                    sortedStruct = [sortedStruct sortedList2];
                else
                    sortedStruct = [sortedStruct sortedList1];
                end


            end
        end


        function found_it = name_in_list(app, student_name, list_of_names)

            found_it = false;

            for k = 1:length(list_of_names)

                if contains(student_name,list_of_names(k))
                    found_it = true;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = zeros(1,6);
            app.UITable.RowName = {"Points"}

        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)

            global filepath
            global filename
            %Open the report file from MATLAB Grader

            [filename , filepath] = uigetfile('*.xls*','Select report file');
            %[numData, textData] = xlsread([filepath,filename]);

            global textData
            textData = readcell([filepath, filename]);

            app.NofileloadedLabel.Text = filename;

        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            global textData

            global filepath
            global filename

            penalty = app.LatePenaltyEditField.Value;
            names = string(split(app.NameListEditField.Value));
            points = app.UITable.Data;

            recordStruct = struct();
            problemNames = [];

            for recordNum = 1:size(textData,1)-1

                nameField = textData{recordNum+1,1};

                %find the space between first and last names and reverse them
                %Reverse name and pad with blanks to make it 20 chars, left-justified
                atIndex = find(' ' == nameField);
                num_blanks = 20-length(nameField);

                recordStruct(recordNum).Name = string([nameField(atIndex:end), ', ',nameField(1:atIndex-1), char(' '*ones(1,num_blanks))]);

                recordStruct(recordNum).Problem = string(textData{recordNum+1,13});

                %if this problem is not already in the list of problem names, add it
                if sum(strcmp(recordStruct(recordNum).Problem, problemNames)) == 0
                    problemNames = [problemNames recordStruct(recordNum).Problem];
                end

                recordStruct(recordNum).Late = textData{recordNum+1,17};

                %strip off the % from the end of the score
                recordStruct(recordNum).Score = str2num(textData{recordNum+1, 11}(1:end-1));
            end

            %Build a list of unique student names

            studentNames =[];
            recordNames = [recordStruct(:).Name];

            numStudents = 0;

            %build a struct array with one element per student, containing all scores
            for recordNum = 1:length(recordStruct)
                nextName = recordStruct(recordNum).Name;
                nextProblem = recordStruct(recordNum).Problem;

                if  sum(points) ==0 %If no array of weights was provided, report the percentage
                    nextScore = recordStruct(recordNum).Score;
                else
                    problemIndex = find(recordStruct(recordNum).Problem==problemNames);
                    nextScore = recordStruct(recordNum).Score*points(problemIndex)/100;
                end

                nextLate = recordStruct(recordNum).Late;

                %If this name is not already in the list of names add it & make a new
                %struct entry
                if sum(strcmp(nextName, studentNames)) == 0
                    studentNames = [studentNames nextName];
                    nextStudent = struct('Name', nextName, 'Problem', nextProblem, 'Score', nextScore, 'Late', nextLate);
                    studentStruct(numStudents+1) =  nextStudent;
                    numStudents = numStudents+1;
                else
                    %if there's already a struct for this student, add the score for
                    %this problem
                    thisStudent = find(strcmp(nextName, studentNames),1,'first');
                    studentStruct(thisStudent).Problem = [studentStruct(thisStudent).Problem, nextProblem];

                    studentStruct(thisStudent).Score = [studentStruct(thisStudent).Score, nextScore];

                    studentStruct(thisStudent).Late = [studentStruct(thisStudent).Late nextLate];
                end
            end

            %make sure the first letter of each name is upper case
            for k = 1:length(studentStruct)

                name_char = char (studentStruct(k).Name);
                first_letter = find(name_char>=65 & name_char<= 122, 1, 'first');
                name_char(first_letter) = upper(name_char(first_letter));
                studentStruct(k).Name = string(name_char);
            end

            studentStruct = sort_struct(app, studentStruct, 'Name');
            %Output a table
            outFileName = [filepath,filename(1:end-5), '.txt'];

            outputFile = fopen(outFileName, 'w');

            fprintf(outputFile,'\n%s\n\n',['     Score Report for ',filename(1:end-5)]);
            fprintf(outputFile,'%s', '      Name            ');

            for prob = 1:length(problemNames)
                fprintf(outputFile,'%13s', problemNames(prob));
            end

            if  sum(points)~=0
                fprintf(outputFile,'      Total\n');
            else
                fprintf(outputFile,'\n');
            end

            %if no list of students provided, output all students; otherwise, only
            %include those in the list
            for studentN = 1:length(studentStruct)
                if isempty(names) || name_in_list (app, studentStruct(studentN).Name, names)
                    fprintf(outputFile,'%s ', studentStruct(studentN).Name);
                    for prob = 1:length(problemNames)

                        problemIndex = find(studentStruct(studentN).Problem==problemNames(prob));
                        if ~isempty(problemIndex)
                            if strcmp(studentStruct(studentN).Late(problemIndex), 'N')

                                fprintf(outputFile,'%10.1f   ', studentStruct(studentN).Score(problemIndex));
                            else
                                studentStruct(studentN).Score(problemIndex) = studentStruct(studentN).Score(problemIndex)*(1-penalty/100)
                                fprintf(outputFile,'%10.1f   ', studentStruct(studentN).Score(problemIndex));
                            end
                        else
                            fprintf(outputFile,'%10i   ', 0);
                        end
                    end

                    %If points were given as an argument, print the total
                    if  sum(points)~=0
                        fprintf(outputFile, '%10.1f', sum(studentStruct(studentN).Score));


                        if sum(studentStruct(studentN).Score) ~= sum(points)
                            fprintf(outputFile, ' ******')
                        end

                    end
                    fprintf(outputFile, '\n');
                end
            end
            fclose(outputFile);
            app.ScoresTextArea.Value = fileread(outFileName);


        end

        % Value changed function: ExerciseDropDown
        function ExerciseDropDownValueChanged(app, event)
            value = app.ExerciseDropDown.Value;
            switch str2num(value)
                case {1,3,5}
                    app.UITable.Data = [2 3 2 3 0 0 ];
                case {2,6}
                    app.UITable.Data = [2 2 3 3 0 0 ];
                case 4
                    app.UITable.Data = [1 3 3 3 0 0 ];
                case 7
                    app.UITable.Data = [2 3 3 2 0 0 ];
                case 8
                    app.UITable.Data = [3 2 3 2 0 0 ];

                case 9
                    app.UITable.Data = [2 2 3 3 4 0];

                case 10
                    app.UITable.Data = [3 2 2 3 0 0 ];
                case {11,13, 14}

                    app.UITable.Data = [10 0 0 0 0 0 ];
                case 12
                    app.UITable.Data = [5 5 0 0 0 0 ];
                case 15
                    app.UITable.Data = [3 3 4 3 3 0 ];

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.NameListEditFieldLabel = uilabel(app.InputTab);
            app.NameListEditFieldLabel.Text = 'Name List';
            app.NameListEditField = uieditfield(app.InputTab, 'text');
            app.ReadFileButton = uibutton(app.InputTab, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'Read File';
            app.GenerateButton = uibutton(app.InputTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.LatePenaltyEditFieldLabel = uilabel(app.InputTab);
            app.LatePenaltyEditFieldLabel.Text = 'Late Penalty (%)';
            app.LatePenaltyEditField = uieditfield(app.InputTab, 'numeric');
            app.LatePenaltyEditField.Value = 20;
            app.MATLABGraderScoreReportGeneratorLabel = uilabel(app.InputTab);
            app.MATLABGraderScoreReportGeneratorLabel.Text = 'MATLAB Grader Score Report Generator';
            app.UITable = uitable(app.InputTab);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true true true];
            app.ProblemLabel = uilabel(app.InputTab);
            app.ProblemLabel.Text = 'Problem';
            app.ExerciseDropDown = uidropdown(app.InputTab);
            app.ExerciseDropDown.Items = {'Exercise', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.ExerciseDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.ExerciseDropDown.ValueChangedFcn = createCallbackFcn(app, @ExerciseDropDownValueChanged, true);
            app.ExerciseDropDown.Value = '0';
            app.NofileloadedLabel = uilabel(app.InputTab);
            app.NofileloadedLabel.Text = 'No file loaded';
            app.ScoresTab = uitab(app.TabGroup);
            app.ScoresTab.Title = 'Scores';
            app.ScoresTextAreaLabel = uilabel(app.ScoresTab);
            app.ScoresTextAreaLabel.Text = 'Scores';
            app.ScoresTextArea = uitextarea(app.ScoresTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScoreReportGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end